classdef DeviceTracking_dev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BackButton           matlab.ui.control.Button
        AddDeviceButton      matlab.ui.control.Button
        HistoryButton        matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        TabGroup             matlab.ui.container.TabGroup
        Tracking             matlab.ui.container.Tab
        ScrollPanel          matlab.ui.container.Panel
        OutcomeMeasureLabel  matlab.ui.control.Label
        TrackingLabel        matlab.ui.control.Label
        ReleasedToLabel      matlab.ui.control.Label
        ReleasedOnLabel      matlab.ui.control.Label
        StatusLabel          matlab.ui.control.Label
        DeviceIDLabel        matlab.ui.control.Label
        History              matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        mo12                 matlab.ui.control.Button
        mo9                  matlab.ui.control.Button
        mo6                  matlab.ui.control.Button
        mo3                  matlab.ui.control.Button
    end


    properties (Access = private)
        saveFolder = pwd; %get the executable folder path
        isSaved = true; %indicates if the data has been saved before closing
        excelTable; %where data is going to be saved
        existingInstances;
        isReturned = false; %return date
        data = struct;
        notes;
        deviceCount;
        Devices; %????

        %Button makers
        Labels;InactiveButtons; DatePickers;ReleaseToFields;TrackingFields;DropDowns;

    end

    methods (Access = private)

        function returnDate(app, subID, outcome)
            %Create a dialog window
            d = uifigure('Name', 'Select Return Date', 'Position', [500 300 400 400], 'WindowStyle', 'modal');

            %add a label
            uilabel(d, 'Text', 'Select a return date:', 'Position', [145 275 150 30]);

            %add a datepicker
            datePicker = uidatepicker(d, 'Position', [125 225 150 30], 'DisplayFormat', 'MM/dd/yyyy');

            %Add an OK button
            uibutton(d, 'Text', 'OK', 'Position', [150 155 100 40], ...
                'ButtonPushedFcn', @(btn, event) saveReturnDate(app, d, datePicker.Value, subID, outcome));
        end

        function saveReturnDate(app, dialog, selectedDate, subID, outcome)
            delete(dialog); %close the dialog

            %store the selected return date in the corresponding cell of History
            %Find row index where subID matches the first column
            rowIndex = find(strcmp(subID, app.excelTable{:,1}));


            % Check if subID exists in the table
            if isempty(rowIndex)
                uialert(app.UIFigure, 'Subject ID not found!', 'Error');
                return;
            end

            % Determine the column based on the outcome measure
            if strcmp(outcome, '3-Month')
                colIndex = 4;
            elseif strcmp(outcome, '6-Month')
                colIndex = 7;
            elseif strcmp(outcome, '9-Month')
                colIndex = 10;
            elseif strcmp(outcome, '12-Month')
                colIndex = 13;
            else
                uialert(app.UIFigure, 'Invalid outcome measure!', 'Error');
                return;
            end

            % Update the return date in the correct cell
            app.excelTable{rowIndex, colIndex} = selectedDate;
            app.isReturned = true;
            SaveButtonPushed(app); %save the results
        end

        function adjustPosition(app) %adjust the window to be centered
            % Get the screen size (in pixels)
            screenSize = get(0, 'ScreenSize');

            % Get the size of the app figure
            figureWidth = app.UIFigure.Position(3);
            figureHeight = app.UIFigure.Position(4);

            % Calculate the position to center the figure
            xPos = (screenSize(3) - figureWidth) / 2;  % Horizontal center
            yPos = (screenSize(4) - figureHeight) / 2; % Vertical center

            % Set the position of the figure
            app.UIFigure.Position = [xPos, yPos, figureWidth, figureHeight];
        end

        function saveInfo(app, fig, table)
            app.data.notes = table;
            savedData = app.data;
            save('\\fs2.smpp.local\smulab2\PROPEL-SCI\Actigraph\data2.mat', '-struct', 'savedData');
            close(fig); %close the window
            return; %return to main code
        end

        function t = convertDateColumns(~, t, date_columns)
            for j = 1:length(date_columns)
                colName = date_columns{j};

                % Skip if already datetime
                if ~iscell(t.(colName))
                    continue;
                end
                colData = t.(colName);  % extract column data (likely cell array)
                newDates = NaT(height(t),1); % Prepare output for new column

                for i = 1:height(t)
                    val = colData{i};  % val can be datetime, empty, or 0x0 double

                    if isempty(val) || (isnumeric(val) && isempty(val)) % empty or empty numeric
                        newDates(i) = NaT;
                    elseif isdatetime(val)
                        newDates(i) = val;
                    elseif ischar(val) || isstring(val)
                        % If inside cell is string or char, parse it
                        newDates(i) = datetime(val, 'InputFormat', 'MM/dd/yyyy', 'Format', 'MM/dd/yyyy');
                    else
                        % For unexpected cases, set NaT
                        newDates(i) = NaT;
                    end
                end

                % Assign cleaned datetime column back to table (overwriting old cell column)
                t.(colName) = newDates;
            end
        end

        function t = convertCellToDouble(~, t, id_columns)
            for j = 1:length(id_columns)
                colName = id_columns{j};

                % Skip column if it's already numeric
                if ~iscell(t.(colName))
                    continue;
                end

                colData = t.(colName);  % Extract cell column
                newVals = NaN(height(t),1);  % Preallocate as NaN (or 0 if you prefer)

                for i = 1:height(t)
                    val = colData{i};

                    if isempty(val) || (isnumeric(val) && isempty(val))
                        newVals(i) = NaN;  % Set empty cells to NaN
                    elseif isnumeric(val)
                        newVals(i) = val;  % Assign numeric value
                    else
                        % Try converting string/char to number if needed
                        numVal = str2double(val);
                        if ~isnan(numVal)
                            newVals(i) = numVal;
                        else
                            newVals(i) = NaN;
                        end
                    end
                end

                t.(colName) = newVals;  % Replace original cell column with numeric array
            end
        end

        function cellTable = cellHighlight(app, cellTable)
            %Create a background style
            s = uistyle("BackgroundColor", [0.95 0.89 0.47], "FontWeight", "bold");

            % Loop through rows and highlight cells where Start date exists but Return date is missing
            for i = 1:size(cellTable, 1)
                startColIndices = [3, 6, 9, 12];  % Start columns
                returnColIndices = [4, 7, 10, 13]; % Return columns

                for j = 1:length(startColIndices)
                    startValue = cellTable{i, startColIndices(j)};
                    returnValue = cellTable{i, returnColIndices(j)};

                    % Check if Start date is present but Return date is missing
                    if ~isempty(startValue) && isempty(returnValue)
                        % Apply the style to the corresponding instance
                        addStyle(app.UITable, s, 'cell', [i, startColIndices(j)-1]);
                        addStyle(app.UITable, s, 'cell', [i, startColIndices(j)]);
                        addStyle(app.UITable, s, 'cell', [i, startColIndices(j)+1]);
                    end
                end
            end
        end

        function excelSave(app)
            varNames = app.data.excel.Properties.VariableNames;
            t = app.UITable.Data;
            t.Properties.VariableNames = varNames;

            date_columns = {'Start_3', 'Return_3', 'Start_6', 'Start_9', 'Start_12', 'Return_3', 'Return_6', 'Return_9', 'Return_12'};
            t = convertDateColumns(app, t, date_columns);

            % Set the display format for all datetime columns
            for j = 1:length(date_columns)
                t.(date_columns{j}) = datetime(t.(date_columns{j}), 'Format', 'MM/dd/yyyy');
            end

            id_columns = {'ID_3', 'ID_6', 'ID_9', 'ID_12'};
            t = convertCellToDouble(app, t, id_columns);

            app.data.excel = t;
            savedData = app.data;
            app.excelTable = app.data.excel;
            save('\\fs2.smpp.local\smulab2\PROPEL-SCI\Actigraph\data2.mat', '-struct', 'savedData');
        end
        
        function createDevice(app, tracking)
            for i = 1:app.deviceCount

                suffix = num2str(i);
                % Grab existing values from the table
                app.existingInstances.(['DropDown_' suffix]) = tracking.(['DropDown_' suffix]);

                idx = suffix;

                % Row layout
                rowHeight = 30;
                baseY = 550;  % starting Y at top of panel
                newY = baseY - (idx-1)*rowHeight;

                % Create row controls inside ScrollPanel
                btn = uibutton(app.ScrollPanel, 'state');
                btn.Text = 'Inactive';
                btn.Position = [116 newY 73 22];
                btn.Tag = sprintf(['_' idx]);

                dp = uidatepicker(app.ScrollPanel);
                dp.Position = [207 newY 128 22];
                dp.Tag = sprintf('DatePicker_%d',idx);

                rt = uieditfield(app.ScrollPanel,'text');
                rt.Position = [350 newY 100 22];
                rt.Tag = sprintf('ReleaseTo_%d',idx);

                tr = uieditfield(app.ScrollPanel,'text');
                tr.Position = [484 newY 100 22];
                tr.Tag = sprintf('Tracking_%d',idx);

                dd = uidropdown(app.ScrollPanel);
                dd.Items = {'','3-Month','6-Month','9-Month','12-Month'};
                dd.Position = [607 newY 100 22];
                dd.Tag = sprintf('DropDown_%d',idx);

                % Save handles to app (like your existing fixed ones)
                % app.(btn.Tag) = btn;
                % app.(dp.Tag) = dp;
                % app.(rt.Tag) = rt;
                % app.(tr.Tag) = tr;
                % app.(dd.Tag) = dd;


                % %===================================================================
                %   % Label
                %   app.Labels(i) = uilabel(app.Tracking);
                %   app.Labels(i).HorizontalAlignment = 'center';
                %   app.Labels(i).FontColor = [0.13 0.13 0.13];
                %   app.Labels(i).Tooltip = {['MOS2E...' num2str(i)]};
                %   app.Labels(i).Position = [52 yStart-(i-1)*(rowHeight) 25 22];
                %   app.Labels(i).Text = num2str(i);
                %
                %   % InactiveButton
                %   app.InactiveButtons(i) = uibutton(app.Tracking, 'state');
                %   app.InactiveButtons(i).ValueChangedFcn = @(src,event)statusButtonValueChanged(app,src,event);
                %   app.InactiveButtons(i).Tag = ['_' num2str(i)];
                %   app.InactiveButtons(i).Text = 'Inactive';
                %   app.InactiveButtons(i).BackgroundColor = [0.96 0.96 0.96];
                %   app.InactiveButtons(i).FontColor = [0.13 0.13 0.13];
                %   app.InactiveButtons(i).Position = [116 yStart-(i-1)*(rowHeight) 73 22];
                %
                %   % DatePicker
                %   app.DatePickers(i) = uidatepicker(app.Tracking);
                %   app.DatePickers(i).FontColor = [0.13 0.13 0.13];
                %   app.DatePickers(i).Position = [207 yStart-(i-1)*(rowHeight) 128 22];
                %
                %   % ReleaseTo
                %   app.ReleaseToFields(i) = uieditfield(app.Tracking,'text');
                %   app.ReleaseToFields(i).FontColor = [0.13 0.13 0.13];
                %   app.ReleaseToFields(i).Position = [350 yStart-(i-1)*(rowHeight) 100 22];
                %
                %   % Shipment Tracking
                %   app.TrackingFields(i) = uieditfield(app.Tracking,'text');
                %   app.TrackingFields(i).FontColor = [0.13 0.13 0.13];
                %   app.TrackingFields(i).Position = [484 yStart-(i-1)*(rowHeight) 100 22];
                %
                %   % DropDown
                %   app.DropDowns(i) = uidropdown(app.Tracking);
                %   app.DropDowns(i).Items = {'','3-Month','6-Month','9-Month','12-Month'};
                %   app.DropDowns(i).FontColor = [0.13 0.13 0.13];
                %   app.DropDowns(i).BackgroundColor = [0.96 0.96 0.96];
                %   app.DropDowns(i).Position = [607 yStart-(i-1)*(rowHeight) 100 22];
                %   app.DropDowns(i).Value = '';
                %
                %    % Update UI elements in the app with loaded value
                %     if app.(['InactiveButton_' suffix]).Value == 1 %if the button is supposed to be active
                %         app.(['InactiveButton_' suffix]).Text = 'Active'; %set button to active
                %         app.(['InactiveButton_' suffix]).BackgroundColor = [0.39,0.83,0.07]; %green color
                %     elseif app.(['InactiveButton_' suffix]).Value == 0 %otherwise disable the ability to change the other values
                %         app.(['DatePicker_' suffix]).Enable = 'off';
                %         app.(['ReleaseTo_' suffix]).Enable = 'off';
                %         app.(['Tracking_' suffix]).Enable = 'off';
                %         app.(['DropDown_' suffix]).Enable = 'off';
                %     end
                %
                %     %enter the values that were loaded
                %     app.(['DatePicker_' suffix]).Value = tracking.(sprintf('DatePicker_%s', suffix));
                %     app.(['ReleaseTo_' suffix]).Value = tracking.(sprintf('ReleaseTo_%s', suffix));
                %     app.(['Tracking_' suffix]).Value = tracking.(sprintf('Tracking_%s', suffix));
                %     app.(['DropDown_' suffix]).Value = tracking.(sprintf('DropDown_%s', suffix));
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            adjustPosition(app);
            app.data = load('\\fs2.smpp.local\smulab2\PROPEL-SCI\Actigraph\data.mat'); %load .mat file
            app.notes = app.data.notes;
            tracking = app.data.tracking;
            app.excelTable = app.data.excel;
            app.deviceCount = length(app.notes);
            
            createDevice(app,tracking);
            % 
            % % Loop through each UI component and update its value
            % for i = 1:app.deviceCount
            %                 suffix = num2str(i);
            %     % Grab existing values from the table
            %     app.existingInstances.(['DropDown_' suffix]) = tracking.(['DropDown_' suffix]);
            % 
            %     % Update UI elements in the app with loaded values
            %     app.(['InactiveButton_' suffix]).Value = tracking.(sprintf('InactiveButton_%s', suffix));
            % 
            %     if app.(['InactiveButton_' suffix]).Value == 1 %if the button is supposed to be active
            %         app.(['InactiveButton_' suffix]).Text = 'Active'; %set button to active
            %         app.(['InactiveButton_' suffix]).BackgroundColor = [0.39,0.83,0.07]; %green color
            %     elseif app.(['InactiveButton_' suffix]).Value == 0 %otherwise disable the ability to change the other values
            %         app.(['DatePicker_' suffix]).Enable = 'off';
            %         app.(['ReleaseTo_' suffix]).Enable = 'off';
            %         app.(['Tracking_' suffix]).Enable = 'off';
            %         app.(['DropDown_' suffix]).Enable = 'off';
            %     end
            % 
            %     %enter the values that were loaded
            %     app.(['DatePicker_' suffix]).Value = tracking.(sprintf('DatePicker_%s', suffix));
            %     app.(['ReleaseTo_' suffix]).Value = tracking.(sprintf('ReleaseTo_%s', suffix));
            %     app.(['Tracking_' suffix]).Value = tracking.(sprintf('Tracking_%s', suffix));
            %     app.(['DropDown_' suffix]).Value = tracking.(sprintf('DropDown_%s', suffix));
            % end
            % 
        end

        % Callback function
        function statusButtonValueChanged(app, event)
            app.isSaved = false; %data has been altered. Make sure user saves when closing out of the app
            clickedButton = event.Source; %get which button is clicked

            buttonSuffix = clickedButton.Tag; % Extract the suffix of the button

            if strcmp(clickedButton.Text, 'Inactive') %If the button that was clicked as already inactive
                clickedButton.Text = 'Active'; %set button to active
                clickedButton.BackgroundColor = [0.39,0.83,0.07]; %green color

                %enable all other variables
                app.(['DatePicker' buttonSuffix]).Enable = 'on';
                app.(['ReleaseTo' buttonSuffix]).Enable = 'on';
                app.(['Tracking' buttonSuffix]).Enable = 'on';
                app.(['DropDown' buttonSuffix]).Enable = 'on';
            else
                clickedButton.Text = 'Inactive'; %set button to inactive
                clickedButton.BackgroundColor = [0.96,0.96,0.96]; %grey color

                outcome = app.(['DropDown' buttonSuffix]).Value; %get the outcome instance
                subID = app.(['ReleaseTo' buttonSuffix]).Value; %get the subject ID
                
                if ~isempty(app.(strcat('ReleaseTo', buttonSuffix)).Value)
                   
                    returnDate(app, subID, outcome); %ask what the return date is
                end

                % Reset the other components in that row
                app.(['DatePicker' buttonSuffix]).Value = NaT;
                app.(['ReleaseTo' buttonSuffix]).Value = '';
                app.(['Tracking' buttonSuffix]).Value = '';
                app.(['DropDown' buttonSuffix]).Value = '';
                % Disable the other components
                app.(['DatePicker' buttonSuffix]).Enable = 'off';
                app.(['ReleaseTo' buttonSuffix]).Enable = 'off';
                app.(['Tracking' buttonSuffix]).Enable = 'off';
                app.(['DropDown' buttonSuffix]).Enable = 'off';
            end
        end

        % Button pushed function: HistoryButton
        function HistoryButtonPushed(app, event)
            %Set buttons
            app.InfoButton.Visible = "off";
            app.SaveButton.Visible = "off";
            app.HistoryButton.Visible = "off";
            app.AddDeviceButton.Visible = "off";
            app.BackButton.Visible = "on";
            
            set(app.TabGroup, 'SelectedTab', app.History);

            % Convert table to cell for manipulation
            cellTable = table2cell(app.excelTable);

            %Loop through the rows and replace NaN and NaT values
            for i = 1:size(cellTable, 1)
                for j = 1:size(cellTable, 2)
                    val = cellTable{i, j};
                    if (isnumeric(val) && isnan(val)) || (isdatetime(val) && isnat(val))
                        cellTable{i, j} = [];
                    end
                end
            end

            cellTable = cellHighlight(app, cellTable);

            % Convert back to table and update UITable
            app.UITable.Data = cell2table(cellTable);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %======== Check if any required fields are missing =======
            for i = 1:app.deviceCount
                suffix = num2str(i);
                if app.(['InactiveButton_' suffix]).Value == 1
                    % Check if any required field is empty
                    if isempty(app.(['ReleaseTo_' suffix]).Value) || ...
                            isempty(app.(['DatePicker_' suffix]).Value) || ...
                            isempty(app.(['DropDown_' suffix]).Value)
                        % Show an alert if any field is missing
                        uialert(app.UIFigure, sprintf('Please fill in all fields for Device %s.', suffix), ...
                            'Missing Information', 'Icon', 'warning');
                        return; % Exit the function if there's missing information
                    end
                end
            end

            %============== Save GUI configuration ===================
            tracking = struct;
            for i = 1:app.deviceCount
                suffix = num2str(i);
                %store the values of everything
                tracking.(sprintf('InactiveButton_%s', suffix)) = app.(['InactiveButton_' suffix]).Value;
                tracking.(sprintf('DatePicker_%s', suffix)) = app.(['DatePicker_' suffix]).Value;
                tracking.(sprintf('ReleaseTo_%s', suffix)) = app.(['ReleaseTo_' suffix]).Value;
                tracking.(sprintf('Tracking_%s', suffix)) = app.(['Tracking_' suffix]).Value;
                tracking.(sprintf('DropDown_%s', suffix)) = app.(['DropDown_' suffix]).Value;
            end
            app.data.tracking = tracking;
            %save(fullfile(app.saveFolder, 'deviceTracking.mat'), 'data');

            %============== Update history.csv file ==================
            %Check if any subject is not there
            for i = 1:app.deviceCount
                suffix = num2str(i);
                newSubID = app.(['ReleaseTo_' suffix]).Value;
                % Find row index where subID matches
                rowIndex = find(strcmp(newSubID, app.excelTable{:, 1}));

                % Check if the newSubID exists in the list
                if  ~isempty(newSubID) %if it is not empty
                    if ~ismember({newSubID}, app.excelTable.SubID)
                        %if the subjectID does not exist, add a new row
                        % Create a new row with empty/default values
                        newRow = cell(1, width(app.excelTable)); % Create a cell array matching the table width

                        for col = 2:width(app.excelTable)
                            if isnumeric(app.excelTable{:, col})  % Check if column is numeric
                                newRow{col} = NaN;  % Assign NaN for numeric columns
                            else
                                newRow{col} = {''};  % Assign empty cell for text columns
                            end
                        end

                        % Determine the column based on the outcome measure
                        if strcmp(app.(['DropDown_' suffix]).Value, '3-Month')
                            colIndex = 2;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '6-Month')
                            colIndex = 5;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '9-Month')
                            colIndex = 8;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '12-Month')
                            colIndex = 11;
                        end

                        newRow{colIndex} = double(string(suffix)); %device ID
                        newRow{colIndex+1} = app.(['DatePicker_' suffix]).Value;
                        newRow{1} = newSubID;

                        % Convert the cell array to a table row
                        newTableRow = cell2table(newRow, 'VariableNames', app.excelTable.Properties.VariableNames);

                        % Append the new row to the table
                        app.excelTable = [app.excelTable; newTableRow];
                    else
                        % If subID exists, update the row
                        if strcmp(app.(['DropDown_' suffix]).Value, '3-Month')
                            colIndex = 2;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '6-Month')
                            colIndex = 5;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '9-Month')
                            colIndex = 8;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '12-Month')
                            colIndex = 11;
                        end

                        existingDate = app.excelTable{rowIndex, colIndex + 1};
                        newInstance = app.(['DropDown_' suffix]).Value;
                        if app.isReturned == false
                            % Only ask to overwrite if any of the key fields are different
                            if ~isnat(existingDate) & ~strcmp(app.existingInstances.(['DropDown_' suffix]), newInstance)

                                choice = uiconfirm(app.UIFigure, ...
                                    sprintf(['Subject "%s" already has data for "%s".\n', ...
                                    'Do you want to overwrite it?'], ...
                                    newSubID, app.(['DropDown_' suffix]).Value), ...
                                    'Confirm Overwrite', ...
                                    'Options', {'Yes', 'No'}, ...
                                    'DefaultOption', 'No', ...
                                    'CancelOption', 'No');

                                if strcmp(choice, 'No')
                                    uialert(app.UIFigure, "Data has NOT been saved!", '', 'Icon', 'warning');
                                    return; % Skip updating this entry
                                end
                            end
                        else
                            app.isReturned = false;
                        end

                        % Update the existing table row
                        app.excelTable{rowIndex, colIndex} = double(string(suffix));
                        app.excelTable{rowIndex, colIndex + 1} = app.(['DatePicker_' suffix]).Value;
                    end
                end
            end


            %save the file
            %writetable(app.excelTable, app.excelFile);
            app.data.excel = app.excelTable;
            savedData = app.data;
            save('\\fs2.smpp.local\smulab2\PROPEL-SCI\Actigraph\data.mat', '-struct', 'savedData');

            app.isSaved = true;
            uialert(app.UIFigure, "Data saved successfully!", '', 'Icon', 'success');

        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            % Create the app window (UI figure)
            fig = uifigure('Position', [500, 100, 500, 310], 'Name', 'Device Information');

            % Define column names for the table
            columnNames = {'Device', 'Note'};

            % Create the editable table inside the popup
            t = uitable(fig, 'Data', app.notes, 'ColumnName', columnNames, 'Position', [20, 50, 460, 260], 'ColumnEditable', [false, true], 'ColumnWidth', {75, 'auto'}); % Make the 'Note' column editable

            % Add a button to save the changes
            uibutton(fig, 'push', 'Text', 'Save Changes', 'Position', [200, 10, 100, 30], ...
                'ButtonPushedFcn', @(btn, event) saveInfo(app, fig, t.Data));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.isSaved
                msg = 'You have unsaved changes. Do you want to save before closing?';
                % Display a confirmation dialog
                choice = uiconfirm(app.UIFigure, msg, 'Save Confirmation', ...
                    'Options', {'Yes', 'No'}, 'Icon', 'warning');

                % Handle user response
                switch choice
                    case 'Yes'
                        % Call your save function here
                        SaveButtonPushed(app);
                        delete(app.UIFigure);

                    case 'No'
                        % Close MATLAB (or delete app if running as standalone)
                        delete(app.UIFigure);
                end
            else
                % If data is saved, close the app directly
                delete(app.UIFigure);
            end

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            %Set buttons
            app.InfoButton.Visible = "on";
            app.SaveButton.Visible = "on";
            app.HistoryButton.Visible = "on";
            app.AddDeviceButton.Visible = "on";
            app.BackButton.Visible = "off";
            
            %save unchanged data
            excelSave(app);

            % Convert table to cell for manipulation
            cellTable = table2cell(app.excelTable);
            cellHighlight(app, cellTable);
            % Convert back to table and update UITable
            app.UITable.Data = cell2table(cellTable);

            set(app.TabGroup, 'SelectedTab', app.Tracking);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            selectedCell = event.Indices; %cell selected
            newData = event.EditData;      %data entered
            dateCol = [3,4,6,7,9,10,12,13];

            %if selected cell is a date column
            if ismember(selectedCell(2), dateCol)

                %newData.Format = 'MM/dd/yyyy';
                newData = datetime(newData, 'InputFormat', 'MM/dd/yy');  % Convert to datetime
                newData.Format = 'MM/dd/yyyy';

                % Convert table to cell for manipulation
                cellTable = table2cell(app.excelTable);
                cellTable{selectedCell(1), selectedCell(2)} = newData;

                % Convert back to table and update UITable
                app.UITable.Data = cell2table(cellTable);

                % Convert table to cell for manipulation
                cellTable = table2cell(app.UITable.Data);
                %Loop through the rows and replace NaN and NaT values
                for i = 1:size(cellTable, 1)
                    for j = 1:size(cellTable, 2)
                        val = cellTable{i, j};
                        if (isnumeric(val) && isnan(val)) || (isdatetime(val) && isnat(val))
                            cellTable{i, j} = [];
                        end
                    end
                end

                app.UITable.Data = cell2table(cellTable);
                excelSave(app);
            else

                cellTable = table2cell(app.UITable.Data);

                val = cellTable{selectedCell(1), selectedCell(2)};
                if (isnumeric(val) && isnan(val)) || (isdatetime(val) && isnat(val))
                    cellTable{selectedCell(1), selectedCell(2)} = [];
                end
                app.UITable.Data = cell2table(cellTable);

            end


        end

        % Button pushed function: AddDeviceButton
        function AddDeviceButtonPushed(app, event)
            % Determine the new row index based on existing rows
            if isprop(app, 'deviceCount')
                app.deviceCount = app.deviceCount + 1;
            else
                app.deviceCount = 1;
            end
            idx = app.deviceCount;

            % Y-position of the new row (adjust spacing as needed)
            rowHeight = 30;  % height between rows
            startY = 523;    % same starting Y as your existing rows
            newY = startY - (idx-1)*rowHeight;

            % Create new label
            label = uilabel(app.Tracking);
            label.Text = num2str(idx);
            label.Position = [52 newY 25 22];
            label.HorizontalAlignment = 'center';

            % Create new Inactive button
            btn = uibutton(app.Tracking, 'state');
            btn.Text = 'Inactive';
            btn.Position = [116 newY 73 22];
            btn.ValueChangedFcn = createCallbackFcn(app,@statusButtonValueChanged,true);
            btn.Tag = ['_' num2str(idx)];

            % Create new DatePicker
            dp = uidatepicker(app.Tracking);
            dp.Position = [207 newY 128 22];

            % Create new ReleaseTo field
            rt = uieditfield(app.Tracking, 'text');
            rt.Position = [350 newY 100 22];

            % Create new Tracking field
            tr = uieditfield(app.Tracking, 'text');
            tr.Position = [484 newY 100 22];

            % Create new DropDown
            dd = uidropdown(app.Tracking);
            dd.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            dd.Position = [607 newY 100 22];

            % Store handles in app for later access if needed
            % app.(['Label_' num2str(idx)]) = label;
            % app.(['InactiveButton_' num2str(idx)]) = btn;
            % app.(['DatePicker_' num2str(idx)]) = dp;
            % app.(['ReleaseTo_' num2str(idx)]) = rt;
            % app.(['Tracking_' num2str(idx)]) = tr;
            % app.(['DropDown_' num2str(idx)]) = dd;

            % Optional: Adjust tab height to accommodate new row
            tabPos = app.Tracking.Position;
            app.Tracking.Position = [tabPos(1) tabPos(2) tabPos(3) tabPos(4) + rowHeight];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Actigraph Device Tracking';
            app.UIFigure.Icon = 'ActigraphLogo.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tracking = uitab(app.TabGroup);
            app.DeviceIDLabel = uilabel(app.Tracking);
            app.DeviceIDLabel.Text = 'Device ID';
            app.StatusLabel = uilabel(app.Tracking);
            app.StatusLabel.Text = 'Status';
            app.ReleasedOnLabel = uilabel(app.Tracking);
            app.ReleasedOnLabel.Text = 'Released On';
            app.ReleasedToLabel = uilabel(app.Tracking);
            app.ReleasedToLabel.Text = 'Released To';
            app.TrackingLabel = uilabel(app.Tracking);
            app.TrackingLabel.Text = 'Tracking #';
            app.OutcomeMeasureLabel = uilabel(app.Tracking);
            app.OutcomeMeasureLabel.Text = 'Outcome Measure';
            app.ScrollPanel = uipanel(app.Tracking);
            app.History = uitab(app.TabGroup);
            app.mo3 = uibutton(app.History, 'push');
            app.mo3.Enable = 'off';
            app.mo3.Text = '3-Month';
            app.mo6 = uibutton(app.History, 'push');
            app.mo6.Enable = 'off';
            app.mo6.Text = '6-Month';
            app.mo9 = uibutton(app.History, 'push');
            app.mo9.Enable = 'off';
            app.mo9.Text = '9-Month';
            app.mo12 = uibutton(app.History, 'push');
            app.mo12.Enable = 'off';
            app.mo12.Text = '12-Month';
            app.UITable = uitable(app.History);
            app.UITable.ColumnName = {'SubID'; 'ID'; 'Start'; 'Return'; 'ID'; 'Start'; 'Return'; 'ID'; 'Start'; 'Return'; 'ID'; 'Start'; 'Return'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true true true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Icon = 'Z:\PROPEL-SCI\Actigraph\DeviceTrackingFiles\DeviceTrackingV2\information-button.png';
            app.InfoButton.Tooltip = {''};
            app.InfoButton.Text = '';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HistoryButton = uibutton(app.UIFigure, 'push');
            app.HistoryButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryButtonPushed, true);
            app.HistoryButton.Text = 'History';
            app.AddDeviceButton = uibutton(app.UIFigure, 'push');
            app.AddDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @AddDeviceButtonPushed, true);
            app.AddDeviceButton.Icon = 'Z:\PROPEL-SCI\Actigraph\DeviceTrackingFiles\DeviceTrackingV2\add.jpg';
            app.AddDeviceButton.Tooltip = {'add another device'};
            app.AddDeviceButton.Text = '';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceTracking_dev

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end