classdef DeviceTracking_prod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BackButton           matlab.ui.control.Button
        HistoryButton        matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        TabGroup             matlab.ui.container.TabGroup
        Tracking             matlab.ui.container.Tab
        Panel                matlab.ui.container.Panel
        DropDown_20          matlab.ui.control.DropDown
        Tracking_20          matlab.ui.control.EditField
        ReleaseTo_20         matlab.ui.control.EditField
        DatePicker_20        matlab.ui.control.DatePicker
        InactiveButton_20    matlab.ui.control.StateButton
        Label_20             matlab.ui.control.Label
        DropDown_19          matlab.ui.control.DropDown
        Tracking_19          matlab.ui.control.EditField
        ReleaseTo_19         matlab.ui.control.EditField
        DatePicker_19        matlab.ui.control.DatePicker
        InactiveButton_19    matlab.ui.control.StateButton
        Label_19             matlab.ui.control.Label
        DropDown_18          matlab.ui.control.DropDown
        Tracking_18          matlab.ui.control.EditField
        ReleaseTo_18         matlab.ui.control.EditField
        DatePicker_18        matlab.ui.control.DatePicker
        InactiveButton_18    matlab.ui.control.StateButton
        Label_18             matlab.ui.control.Label
        DropDown_17          matlab.ui.control.DropDown
        Tracking_17          matlab.ui.control.EditField
        ReleaseTo_17         matlab.ui.control.EditField
        DatePicker_17        matlab.ui.control.DatePicker
        InactiveButton_17    matlab.ui.control.StateButton
        Label_17             matlab.ui.control.Label
        DropDown_16          matlab.ui.control.DropDown
        Tracking_16          matlab.ui.control.EditField
        ReleaseTo_16         matlab.ui.control.EditField
        DatePicker_16        matlab.ui.control.DatePicker
        InactiveButton_16    matlab.ui.control.StateButton
        Label_16             matlab.ui.control.Label
        DropDown_15          matlab.ui.control.DropDown
        Tracking_15          matlab.ui.control.EditField
        ReleaseTo_15         matlab.ui.control.EditField
        DatePicker_15        matlab.ui.control.DatePicker
        InactiveButton_15    matlab.ui.control.StateButton
        Label_15             matlab.ui.control.Label
        DropDown_14          matlab.ui.control.DropDown
        Tracking_14          matlab.ui.control.EditField
        ReleaseTo_14         matlab.ui.control.EditField
        DatePicker_14        matlab.ui.control.DatePicker
        InactiveButton_14    matlab.ui.control.StateButton
        Label_14             matlab.ui.control.Label
        DropDown_13          matlab.ui.control.DropDown
        Tracking_13          matlab.ui.control.EditField
        ReleaseTo_13         matlab.ui.control.EditField
        DatePicker_13        matlab.ui.control.DatePicker
        InactiveButton_13    matlab.ui.control.StateButton
        Label_13             matlab.ui.control.Label
        DropDown_12          matlab.ui.control.DropDown
        Tracking_12          matlab.ui.control.EditField
        ReleaseTo_12         matlab.ui.control.EditField
        DatePicker_12        matlab.ui.control.DatePicker
        InactiveButton_12    matlab.ui.control.StateButton
        Label_12             matlab.ui.control.Label
        DropDown_11          matlab.ui.control.DropDown
        Tracking_11          matlab.ui.control.EditField
        ReleaseTo_11         matlab.ui.control.EditField
        DatePicker_11        matlab.ui.control.DatePicker
        InactiveButton_11    matlab.ui.control.StateButton
        Label_11             matlab.ui.control.Label
        DropDown_10          matlab.ui.control.DropDown
        Tracking_10          matlab.ui.control.EditField
        ReleaseTo_10         matlab.ui.control.EditField
        DatePicker_10        matlab.ui.control.DatePicker
        InactiveButton_10    matlab.ui.control.StateButton
        Label_10             matlab.ui.control.Label
        DropDown_9           matlab.ui.control.DropDown
        Tracking_9           matlab.ui.control.EditField
        ReleaseTo_9          matlab.ui.control.EditField
        DatePicker_9         matlab.ui.control.DatePicker
        InactiveButton_9     matlab.ui.control.StateButton
        Label_9              matlab.ui.control.Label
        DropDown_8           matlab.ui.control.DropDown
        Tracking_8           matlab.ui.control.EditField
        ReleaseTo_8          matlab.ui.control.EditField
        DatePicker_8         matlab.ui.control.DatePicker
        InactiveButton_8     matlab.ui.control.StateButton
        Label_8              matlab.ui.control.Label
        DropDown_7           matlab.ui.control.DropDown
        Tracking_7           matlab.ui.control.EditField
        ReleaseTo_7          matlab.ui.control.EditField
        DatePicker_7         matlab.ui.control.DatePicker
        InactiveButton_7     matlab.ui.control.StateButton
        Label_7              matlab.ui.control.Label
        DropDown_6           matlab.ui.control.DropDown
        Tracking_6           matlab.ui.control.EditField
        ReleaseTo_6          matlab.ui.control.EditField
        DatePicker_6         matlab.ui.control.DatePicker
        InactiveButton_6     matlab.ui.control.StateButton
        Label_6              matlab.ui.control.Label
        DropDown_5           matlab.ui.control.DropDown
        Tracking_5           matlab.ui.control.EditField
        ReleaseTo_5          matlab.ui.control.EditField
        DatePicker_5         matlab.ui.control.DatePicker
        InactiveButton_5     matlab.ui.control.StateButton
        Label_5              matlab.ui.control.Label
        DropDown_4           matlab.ui.control.DropDown
        Tracking_4           matlab.ui.control.EditField
        ReleaseTo_4          matlab.ui.control.EditField
        DatePicker_4         matlab.ui.control.DatePicker
        InactiveButton_4     matlab.ui.control.StateButton
        Label_4              matlab.ui.control.Label
        DropDown_3           matlab.ui.control.DropDown
        Tracking_3           matlab.ui.control.EditField
        ReleaseTo_3          matlab.ui.control.EditField
        DatePicker_3         matlab.ui.control.DatePicker
        InactiveButton_3     matlab.ui.control.StateButton
        Label_3              matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        Tracking_2           matlab.ui.control.EditField
        ReleaseTo_2          matlab.ui.control.EditField
        DatePicker_2         matlab.ui.control.DatePicker
        InactiveButton_2     matlab.ui.control.StateButton
        Label_2              matlab.ui.control.Label
        DropDown_1           matlab.ui.control.DropDown
        Tracking_1           matlab.ui.control.EditField
        ReleaseTo_1          matlab.ui.control.EditField
        DatePicker_1         matlab.ui.control.DatePicker
        InactiveButton_1     matlab.ui.control.StateButton
        Label_1              matlab.ui.control.Label
        OutcomeMeasureLabel  matlab.ui.control.Label
        TrackingLabel        matlab.ui.control.Label
        ReleasedToLabel      matlab.ui.control.Label
        ReleasedOnLabel      matlab.ui.control.Label
        StatusLabel          matlab.ui.control.Label
        DeviceIDLabel        matlab.ui.control.Label
        History              matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        mo12                 matlab.ui.control.Button
        mo9                  matlab.ui.control.Button
        mo6                  matlab.ui.control.Button
        mo3                  matlab.ui.control.Button
    end


    properties (Access = private)
        saveFolder = pwd; %get the executable folder path
        isSaved = true; %indicates if the data has been saved before closing
        excelTable; %where data is going to be saved
        existingInstances;
        isReturned = false; %return date
        data = struct;
        notes;
        deviceCount;
        currentFilePath;
    end

    methods (Access = private)

        function returnDate(app, subID, outcome)
            %Create a dialog window
            d = uifigure('Name', 'Select Return Date', 'Position', [500 300 400 400], 'WindowStyle', 'modal');

            %add a label
            uilabel(d, 'Text', 'Select a return date:', 'Position', [145 275 150 30]);

            %add a datepicker
            datePicker = uidatepicker(d, 'Position', [125 225 150 30], 'DisplayFormat', 'MM/dd/yyyy');

            %Add an OK button
            uibutton(d, 'Text', 'OK', 'Position', [150 155 100 40], ...
                'ButtonPushedFcn', @(btn, event) saveReturnDate(app, d, datePicker.Value, subID, outcome));
        end

        function saveReturnDate(app, dialog, selectedDate, subID, outcome)
            delete(dialog); %close the dialog

            %store the selected return date in the corresponding cell of History
            %Find row index where subID matches the first column
            rowIndex = find(strcmp(subID, app.excelTable{:,1}));


            % Check if subID exists in the table
            if isempty(rowIndex)
                uialert(app.UIFigure, 'Subject ID not found!', 'Error');
                return;
            end

            % Determine the column based on the outcome measure
            if strcmp(outcome, '3-Month')
                colIndex = 4;
            elseif strcmp(outcome, '6-Month')
                colIndex = 7;
            elseif strcmp(outcome, '9-Month')
                colIndex = 10;
            elseif strcmp(outcome, '12-Month')
                colIndex = 13;
            else
                uialert(app.UIFigure, 'Invalid outcome measure!', 'Error');
                return;
            end

            % Update the return date in the correct cell
            app.excelTable{rowIndex, colIndex} = selectedDate;
            app.isReturned = true;
            SaveButtonPushed(app); %save the results
        end

        function adjustPosition(app) %adjust the window to be centered
            % Get the screen size (in pixels)
            screenSize = get(0, 'ScreenSize');

            % Get the size of the app figure
            figureWidth = app.UIFigure.Position(3);
            figureHeight = app.UIFigure.Position(4);

            % Calculate the position to center the figure
            xPos = (screenSize(3) - figureWidth) / 2;  % Horizontal center
            yPos = (screenSize(4) - figureHeight) / 2; % Vertical center

            % Set the position of the figure
            app.UIFigure.Position = [xPos, yPos, figureWidth, figureHeight];
        end

        function saveInfo(app, fig, table)
            app.data.notes = table;
            savedData = app.data;

            % If the file is new and has no path yet, ask user
            if isempty(app.currentFilePath)
                [file, path] = uiputfile('*.mat', 'Save file as');
                if isequal(file,0)
                    disp('User canceled saving.');
                    return;
                end
                app.currentFilePath = fullfile(path, file);
            end

            % Save to the current file path
            save(app.currentFilePath, '-struct', 'savedData');

            close(fig); %close the window
            return; %return to main code
        end

        function t = convertDateColumns(~, t, date_columns)
            for j = 1:length(date_columns)
                colName = date_columns{j};

                % Skip if already datetime
                if ~iscell(t.(colName))
                    continue;
                end
                colData = t.(colName);  % extract column data (likely cell array)
                newDates = NaT(height(t),1); % Prepare output for new column

                for i = 1:height(t)
                    val = colData{i};  % val can be datetime, empty, or 0x0 double

                    if isempty(val) || (isnumeric(val) && isempty(val)) % empty or empty numeric
                        newDates(i) = NaT;
                    elseif isdatetime(val)
                        newDates(i) = val;
                    elseif ischar(val) || isstring(val)
                        % If inside cell is string or char, parse it
                        newDates(i) = datetime(val, 'InputFormat', 'MM/dd/yyyy', 'Format', 'MM/dd/yyyy');
                    else
                        % For unexpected cases, set NaT
                        newDates(i) = NaT;
                    end
                end

                % Assign cleaned datetime column back to table (overwriting old cell column)
                t.(colName) = newDates;
            end
        end

        function t = convertCellToDouble(~, t, id_columns)
            for j = 1:length(id_columns)
                colName = id_columns{j};

                % Skip column if it's already numeric
                if ~iscell(t.(colName))
                    continue;
                end

                colData = t.(colName);  % Extract cell column
                newVals = NaN(height(t),1);  % Preallocate as NaN (or 0 if you prefer)

                for i = 1:height(t)
                    val = colData{i};

                    if isempty(val) || (isnumeric(val) && isempty(val))
                        newVals(i) = NaN;  % Set empty cells to NaN
                    elseif isnumeric(val)
                        newVals(i) = val;  % Assign numeric value
                    else
                        % Try converting string/char to number if needed
                        numVal = str2double(val);
                        if ~isnan(numVal)
                            newVals(i) = numVal;
                        else
                            newVals(i) = NaN;
                        end
                    end
                end

                t.(colName) = newVals;  % Replace original cell column with numeric array
            end
        end

        function cellTable = cellHighlight(app, cellTable)
            %Create a background style
            s = uistyle("BackgroundColor", [0.95 0.89 0.47], "FontWeight", "bold");

            % Loop through rows and highlight cells where Start date exists but Return date is missing
            for i = 1:size(cellTable, 1)
                startColIndices = [3, 6, 9, 12];  % Start columns
                returnColIndices = [4, 7, 10, 13]; % Return columns

                for j = 1:length(startColIndices)
                    startValue = cellTable{i, startColIndices(j)};
                    returnValue = cellTable{i, returnColIndices(j)};

                    % Check if Start date is present but Return date is missing
                    if ~isempty(startValue) && isempty(returnValue)
                        % Apply the style to the corresponding instance
                        addStyle(app.UITable, s, 'cell', [i, startColIndices(j)-1]);
                        addStyle(app.UITable, s, 'cell', [i, startColIndices(j)]);
                        addStyle(app.UITable, s, 'cell', [i, startColIndices(j)+1]);
                    end
                end
            end
        end

        function excelSave(app)
            varNames = app.data.excel.Properties.VariableNames;
            t = app.UITable.Data;
            t.Properties.VariableNames = varNames;

            date_columns = {'Start_3', 'Return_3', 'Start_6', 'Start_9', 'Start_12', 'Return_3', 'Return_6', 'Return_9', 'Return_12'};
            t = convertDateColumns(app, t, date_columns);

            % Set the display format for all datetime columns
            for j = 1:length(date_columns)
                t.(date_columns{j}) = datetime(t.(date_columns{j}), 'Format', 'MM/dd/yyyy');
            end

            id_columns = {'ID_3', 'ID_6', 'ID_9', 'ID_12'};
            t = convertCellToDouble(app, t, id_columns);

            app.data.excel = t;
            savedData = app.data;
            app.excelTable = app.data.excel;
            % Update data before saving

            % If the file is new and has no path yet, ask user
            if isempty(app.currentFilePath)
                [file, path] = uiputfile('*.mat', 'Save file as');
                if isequal(file,0)
                    disp('User canceled saving.');
                    return;
                end
                app.currentFilePath = fullfile(path, file);
            end

            % Save to the current file path
            save(app.currentFilePath, '-struct', 'savedData');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            adjustPosition(app);
            
            mlock %prevent memory clearing for recent files. If changes are made to the code, run munlock before running again            
            
            persistent recentFiles  % stores paths for the session, like Word recent files

            if isempty(recentFiles)
                recentFiles = {}; % initialize on first call
            end

            fileLoaded = false; %flag to control the loop

            while ~fileLoaded
                % Ask user to load or create a file
                choice = questdlg('Do you want to create a new file or load an existing file?', ...
                    'File Option', ...
                    'Load File','Create New', 'Cancel','Load File'); % default is 'Load File'

                switch choice
                    case 'Load File'
                        % Use last recent file as default if available
                        if ~isempty(recentFiles)
                            defaultPath = recentFiles{1};
                        else
                            defaultPath = pwd; % fallback to current folder
                        end

                        [file, path] = uigetfile('*.mat', 'Select a .mat file', defaultPath);
                        if isequal(file,0)
                            % User canceled loading, go back to popup
                            continue;  % restart the loop
                        end

                        fullFilePath = fullfile(path, file);
                        app.data = load(fullFilePath);

                        % Remember this file
                        recentFiles = [{fullFilePath}, recentFiles(~strcmp(recentFiles, fullFilePath))];
                        % recent file goes to top, remove duplicates

                        fileLoaded = true; %exit loop

                    case 'Create New'
                        [file, path] = uiputfile('*.mat', 'Create new .mat file');
                        if isequal(file,0)
                            % User canceled loading, go back to popup
                            continue;  % restart the loop
                        end
                        fullFilePath = fullfile(path, file);
                        % Initialize empty structures
                        app.data.notes = {};
                        app.data.tracking = struct();
                        app.data.excel = table();
                        savedData = app.data;
                        save(fullFilePath, '-struct', 'savedData'); % save empty template
                        fileLoaded = true;
                    case 'Cancel'
                        pause(1);
                        delete(app.UIFigure);
                        return;
                end
            end
            app.currentFilePath = fullFilePath;
            app.notes = app.data.notes;
            tracking = app.data.tracking;
            app.excelTable = app.data.excel;
            app.deviceCount = length(app.notes);

            % Loop through each UI component and update its value
            for i = 1:app.deviceCount
                suffix = num2str(i);
                % Grab existing values from the table
                app.existingInstances.(['DropDown_' suffix]) = tracking.(['DropDown_' suffix]);

                % Update UI elements in the app with loaded values
                app.(['InactiveButton_' suffix]).Value = tracking.(sprintf('InactiveButton_%s', suffix));

                if app.(['InactiveButton_' suffix]).Value == 1 %if the button is supposed to be active
                    app.(['InactiveButton_' suffix]).Text = 'Active'; %set button to active
                    app.(['InactiveButton_' suffix]).BackgroundColor = [0.39,0.83,0.07]; %green color
                    app.(['DatePicker_' suffix]).Enable = 'on';
                    app.(['ReleaseTo_' suffix]).Enable = 'on';
                    app.(['Tracking_' suffix]).Enable = 'on';
                    app.(['DropDown_' suffix]).Enable = 'on';
                end

                %enter the values that were loaded
                app.(['DatePicker_' suffix]).Value = tracking.(sprintf('DatePicker_%s', suffix));
                app.(['ReleaseTo_' suffix]).Value = tracking.(sprintf('ReleaseTo_%s', suffix));
                app.(['Tracking_' suffix]).Value = tracking.(sprintf('Tracking_%s', suffix));
                app.(['DropDown_' suffix]).Value = tracking.(sprintf('DropDown_%s', suffix));
            end

        end

        % Value changed function: InactiveButton_1, 
        % InactiveButton_10, InactiveButton_11, InactiveButton_12, 
        % InactiveButton_13, InactiveButton_14, InactiveButton_15, 
        % InactiveButton_16, InactiveButton_17, InactiveButton_18, 
        % InactiveButton_19, InactiveButton_2, InactiveButton_20, 
        % InactiveButton_3, InactiveButton_4, InactiveButton_5, 
        % InactiveButton_6, InactiveButton_7, InactiveButton_8, 
        % InactiveButton_9
        function statusButtonValueChanged(app, event)
            app.isSaved = false; %data has been altered. Make sure user saves when closing out of the app
            clickedButton = event.Source; %get which button is clicked

            buttonSuffix = clickedButton.Tag; % Extract the suffix of the button

            if strcmp(clickedButton.Text, 'Inactive') %If the button that was clicked as already inactive
                clickedButton.Text = 'Active'; %set button to active
                clickedButton.BackgroundColor = [0.39,0.83,0.07]; %green color

                %enable all other variables
                app.(['DatePicker' buttonSuffix]).Enable = 'on';
                app.(['ReleaseTo' buttonSuffix]).Enable = 'on';
                app.(['Tracking' buttonSuffix]).Enable = 'on';
                app.(['DropDown' buttonSuffix]).Enable = 'on';
            else
                clickedButton.Text = 'Inactive'; %set button to inactive
                clickedButton.BackgroundColor = [0.96,0.96,0.96]; %grey color

                outcome = app.(['DropDown' buttonSuffix]).Value; %get the outcome instance
                subID = app.(['ReleaseTo' buttonSuffix]).Value; %get the subject ID

                if ~isempty(app.(strcat('ReleaseTo', buttonSuffix)).Value)

                    returnDate(app, subID, outcome); %ask what the return date is
                end

                % Reset the other components in that row
                app.(['DatePicker' buttonSuffix]).Value = NaT;
                app.(['ReleaseTo' buttonSuffix]).Value = '';
                app.(['Tracking' buttonSuffix]).Value = '';
                app.(['DropDown' buttonSuffix]).Value = '';
                % Disable the other components
                app.(['DatePicker' buttonSuffix]).Enable = 'off';
                app.(['ReleaseTo' buttonSuffix]).Enable = 'off';
                app.(['Tracking' buttonSuffix]).Enable = 'off';
                app.(['DropDown' buttonSuffix]).Enable = 'off';
            end
        end

        % Button pushed function: HistoryButton
        function HistoryButtonPushed(app, event)
            %Set buttons
            app.SaveButton.Visible = "off";
            app.HistoryButton.Visible = "off";
            app.BackButton.Visible = "on";

            set(app.TabGroup, 'SelectedTab', app.History);

            % Convert table to cell for manipulation
            cellTable = table2cell(app.excelTable);

            %Loop through the rows and replace NaN and NaT values
            for i = 1:size(cellTable, 1)
                for j = 1:size(cellTable, 2)
                    val = cellTable{i, j};
                    if (isnumeric(val) && isnan(val)) || (isdatetime(val) && isnat(val))
                        cellTable{i, j} = [];
                    end
                end
            end

            cellTable = cellHighlight(app, cellTable);

            % Convert back to table and update UITable
            app.UITable.Data = cell2table(cellTable);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %======== Check if any required fields are missing =======
            for i = 1:app.deviceCount
                suffix = num2str(i);
                if app.(['InactiveButton_' suffix]).Value == 1
                    % Check if any required field is empty
                    if isempty(app.(['ReleaseTo_' suffix]).Value) || ...
                            isempty(app.(['DatePicker_' suffix]).Value) || ...
                            isempty(app.(['DropDown_' suffix]).Value)
                        % Show an alert if any field is missing
                        uialert(app.UIFigure, sprintf('Please fill in all fields for Device %s.', suffix), ...
                            'Missing Information', 'Icon', 'warning');
                        return; % Exit the function if there's missing information
                    end
                end
            end

            %============== Save GUI configuration ===================
            tracking = struct;
            for i = 1:app.deviceCount
                suffix = num2str(i);
                %store the values of everything
                tracking.(sprintf('InactiveButton_%s', suffix)) = app.(['InactiveButton_' suffix]).Value;
                tracking.(sprintf('DatePicker_%s', suffix)) = app.(['DatePicker_' suffix]).Value;
                tracking.(sprintf('ReleaseTo_%s', suffix)) = app.(['ReleaseTo_' suffix]).Value;
                tracking.(sprintf('Tracking_%s', suffix)) = app.(['Tracking_' suffix]).Value;
                tracking.(sprintf('DropDown_%s', suffix)) = app.(['DropDown_' suffix]).Value;
            end
            app.data.tracking = tracking;
            %save(fullfile(app.saveFolder, 'deviceTracking.mat'), 'data');

            %============== Update history.csv file ==================
            %Check if any subject is not there
            for i = 1:app.deviceCount
                suffix = num2str(i);
                newSubID = app.(['ReleaseTo_' suffix]).Value;
                % Find row index where subID matches
                rowIndex = find(strcmp(newSubID, app.excelTable{:, 1}));

                % Check if the newSubID exists in the list
                if  ~isempty(newSubID) %if it is not empty
                    if ~ismember({newSubID}, app.excelTable.SubID)
                        %if the subjectID does not exist, add a new row
                        % Create a new row with empty/default values
                        newRow = cell(1, width(app.excelTable)); % Create a cell array matching the table width

                        for col = 2:width(app.excelTable)
                            if isnumeric(app.excelTable{:, col})  % Check if column is numeric
                                newRow{col} = NaN;  % Assign NaN for numeric columns
                            else
                                newRow{col} = {''};  % Assign empty cell for text columns
                            end
                        end

                        % Determine the column based on the outcome measure
                        if strcmp(app.(['DropDown_' suffix]).Value, '3-Month')
                            colIndex = 2;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '6-Month')
                            colIndex = 5;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '9-Month')
                            colIndex = 8;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '12-Month')
                            colIndex = 11;
                        end

                        newRow{colIndex} = double(string(suffix)); %device ID
                        newRow{colIndex+1} = app.(['DatePicker_' suffix]).Value;
                        newRow{1} = newSubID;

                        % Convert the cell array to a table row
                        newTableRow = cell2table(newRow, 'VariableNames', app.excelTable.Properties.VariableNames);

                        % Append the new row to the table
                        app.excelTable = [app.excelTable; newTableRow];
                    else
                        % If subID exists, update the row
                        if strcmp(app.(['DropDown_' suffix]).Value, '3-Month')
                            colIndex = 2;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '6-Month')
                            colIndex = 5;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '9-Month')
                            colIndex = 8;
                        elseif strcmp(app.(['DropDown_' suffix]).Value, '12-Month')
                            colIndex = 11;
                        end

                        existingDate = app.excelTable{rowIndex, colIndex + 1};
                        newInstance = app.(['DropDown_' suffix]).Value;
                        if app.isReturned == false
                            % Only ask to overwrite if any of the key fields are different
                            if ~isnat(existingDate) & ~strcmp(app.existingInstances.(['DropDown_' suffix]), newInstance)

                                choice = uiconfirm(app.UIFigure, ...
                                    sprintf(['Subject "%s" already has data for "%s".\n', ...
                                    'Do you want to overwrite it?'], ...
                                    newSubID, app.(['DropDown_' suffix]).Value), ...
                                    'Confirm Overwrite', ...
                                    'Options', {'Yes', 'No'}, ...
                                    'DefaultOption', 'No', ...
                                    'CancelOption', 'No');

                                if strcmp(choice, 'No')
                                    uialert(app.UIFigure, "Data has NOT been saved!", '', 'Icon', 'warning');
                                    return; % Skip updating this entry
                                end
                            end
                        else
                            app.isReturned = false;
                        end

                        % Update the existing table row
                        app.excelTable{rowIndex, colIndex} = double(string(suffix));
                        app.excelTable{rowIndex, colIndex + 1} = app.(['DatePicker_' suffix]).Value;
                    end
                end
            end

            % Update data before saving
            app.data.excel = app.excelTable;
            savedData = app.data;

            % If the file is new and has no path yet, ask user
            if isempty(app.currentFilePath)
                [file, path] = uiputfile('*.mat', 'Save file as');
                if isequal(file,0)
                    disp('User canceled saving.');
                    return;
                end
                app.currentFilePath = fullfile(path, file);
            end

            % Save to the current file path
            save(app.currentFilePath, '-struct', 'savedData');

            app.isSaved = true;
            uialert(app.UIFigure, "Data saved successfully!", '', 'Icon', 'success');

        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            % Create the app window (UI figure)
            fig = uifigure('Position', [500, 100, 500, 310], 'Name', 'Device Information');

            % Define column names for the table
            columnNames = {'Device', 'Serial #', 'Note'};

            % Create the editable table inside the popup
            t = uitable(fig, 'Data', app.notes, 'ColumnName', columnNames, 'Position', [20, 50, 460, 260], 'ColumnEditable', [false, true], 'ColumnWidth', {75, 'auto'}); % Make the 'Note' column editable

            % Add a button to save the changes
            uibutton(fig, 'push', 'Text', 'Save Changes', 'Position', [200, 10, 100, 30], ...
                'ButtonPushedFcn', @(btn, event) saveInfo(app, fig, t.Data));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.isSaved
                msg = 'You have unsaved changes. Do you want to save before closing?';
                % Display a confirmation dialog
                choice = uiconfirm(app.UIFigure, msg, 'Save Confirmation', ...
                    'Options', {'Yes', 'No'}, 'Icon', 'warning');

                % Handle user response
                switch choice
                    case 'Yes'
                        % Call your save function here
                        SaveButtonPushed(app);
                        delete(app.UIFigure);

                    case 'No'
                        % Close MATLAB (or delete app if running as standalone)
                        delete(app.UIFigure);
                end
            else
                % If data is saved, close the app directly
                delete(app.UIFigure);
            end

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            %Set buttons
            app.SaveButton.Visible = "on";
            app.HistoryButton.Visible = "on";
            app.BackButton.Visible = "off";

            %save unchanged data
            excelSave(app);

            % Convert table to cell for manipulation
            cellTable = table2cell(app.excelTable);
            cellHighlight(app, cellTable);
            % Convert back to table and update UITable
            app.UITable.Data = cell2table(cellTable);

            set(app.TabGroup, 'SelectedTab', app.Tracking);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            selectedCell = event.Indices; %cell selected
            newData = event.EditData;      %data entered
            dateCol = [3,4,6,7,9,10,12,13];

            %if selected cell is a date column
            if ismember(selectedCell(2), dateCol)

                %newData.Format = 'MM/dd/yyyy';
                newData = datetime(newData, 'InputFormat', 'MM/dd/yy');  % Convert to datetime
                newData.Format = 'MM/dd/yyyy';

                % Convert table to cell for manipulation
                cellTable = table2cell(app.excelTable);
                cellTable{selectedCell(1), selectedCell(2)} = newData;

                % Convert back to table and update UITable
                app.UITable.Data = cell2table(cellTable);

                % Convert table to cell for manipulation
                cellTable = table2cell(app.UITable.Data);
                %Loop through the rows and replace NaN and NaT values
                for i = 1:size(cellTable, 1)
                    for j = 1:size(cellTable, 2)
                        val = cellTable{i, j};
                        if (isnumeric(val) && isnan(val)) || (isdatetime(val) && isnat(val))
                            cellTable{i, j} = [];
                        end
                    end
                end

                app.UITable.Data = cell2table(cellTable);
                excelSave(app);
            else

                cellTable = table2cell(app.UITable.Data);

                val = cellTable{selectedCell(1), selectedCell(2)};
                if (isnumeric(val) && isnan(val)) || (isdatetime(val) && isnat(val))
                    cellTable{selectedCell(1), selectedCell(2)} = [];
                end
                app.UITable.Data = cell2table(cellTable);

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Actigraph Device Tracking';
            app.UIFigure.Icon = 'ActigraphLogo.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tracking = uitab(app.TabGroup);
            app.DeviceIDLabel = uilabel(app.Tracking);
            app.DeviceIDLabel.Text = 'Device ID';
            app.StatusLabel = uilabel(app.Tracking);
            app.StatusLabel.Text = 'Status';
            app.ReleasedOnLabel = uilabel(app.Tracking);
            app.ReleasedOnLabel.Text = 'Released On';
            app.ReleasedToLabel = uilabel(app.Tracking);
            app.ReleasedToLabel.Text = 'Released To';
            app.TrackingLabel = uilabel(app.Tracking);
            app.TrackingLabel.Text = 'Tracking #';
            app.OutcomeMeasureLabel = uilabel(app.Tracking);
            app.OutcomeMeasureLabel.Text = 'Outcome Measure';
            app.Panel = uipanel(app.Tracking);
            app.Label_1 = uilabel(app.Panel);
            app.Label_1.Tooltip = {'MOS2E34230577'};
            app.Label_1.Text = '1';
            app.InactiveButton_1 = uibutton(app.Panel, 'state');
            app.InactiveButton_1.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_1.Tag = '_1';
            app.InactiveButton_1.Text = 'Inactive';
            app.DatePicker_1 = uidatepicker(app.Panel);
            app.DatePicker_1.Enable = 'off';
            app.ReleaseTo_1 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_1.Enable = 'off';
            app.Tracking_1 = uieditfield(app.Panel, 'text');
            app.Tracking_1.Enable = 'off';
            app.DropDown_1 = uidropdown(app.Panel);
            app.DropDown_1.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_1.Enable = 'off';
            app.DropDown_1.Value = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Tooltip = {'MOS2E34230622'};
            app.Label_2.Text = '2';
            app.InactiveButton_2 = uibutton(app.Panel, 'state');
            app.InactiveButton_2.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_2.Tag = '_2';
            app.InactiveButton_2.Text = 'Inactive';
            app.DatePicker_2 = uidatepicker(app.Panel);
            app.DatePicker_2.Enable = 'off';
            app.ReleaseTo_2 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_2.Enable = 'off';
            app.Tracking_2 = uieditfield(app.Panel, 'text');
            app.Tracking_2.Enable = 'off';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Tooltip = {'MOS2E34230113'};
            app.Label_3.Text = '3';
            app.InactiveButton_3 = uibutton(app.Panel, 'state');
            app.InactiveButton_3.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_3.Tag = '_3';
            app.InactiveButton_3.Text = 'Inactive';
            app.DatePicker_3 = uidatepicker(app.Panel);
            app.DatePicker_3.Enable = 'off';
            app.ReleaseTo_3 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_3.Enable = 'off';
            app.Tracking_3 = uieditfield(app.Panel, 'text');
            app.Tracking_3.Enable = 'off';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = '';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Tooltip = {'MOS2E34230098'};
            app.Label_4.Text = '4';
            app.InactiveButton_4 = uibutton(app.Panel, 'state');
            app.InactiveButton_4.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_4.Tag = '_4';
            app.InactiveButton_4.Text = 'Inactive';
            app.DatePicker_4 = uidatepicker(app.Panel);
            app.DatePicker_4.Enable = 'off';
            app.ReleaseTo_4 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_4.Enable = 'off';
            app.Tracking_4 = uieditfield(app.Panel, 'text');
            app.Tracking_4.Enable = 'off';
            app.DropDown_4 = uidropdown(app.Panel);
            app.DropDown_4.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_4.Enable = 'off';
            app.DropDown_4.Value = '';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Tooltip = {'MOS2E45230849'};
            app.Label_5.Text = '5';
            app.InactiveButton_5 = uibutton(app.Panel, 'state');
            app.InactiveButton_5.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_5.Tag = '_5';
            app.InactiveButton_5.Text = 'Inactive';
            app.DatePicker_5 = uidatepicker(app.Panel);
            app.DatePicker_5.Enable = 'off';
            app.ReleaseTo_5 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_5.Enable = 'off';
            app.Tracking_5 = uieditfield(app.Panel, 'text');
            app.Tracking_5.Enable = 'off';
            app.DropDown_5 = uidropdown(app.Panel);
            app.DropDown_5.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_5.Enable = 'off';
            app.DropDown_5.Value = '';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Tooltip = {'MOS2E45230832'};
            app.Label_6.Text = '7';
            app.InactiveButton_6 = uibutton(app.Panel, 'state');
            app.InactiveButton_6.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_6.Tag = '_7';
            app.InactiveButton_6.Text = 'Inactive';
            app.DatePicker_6 = uidatepicker(app.Panel);
            app.DatePicker_6.Enable = 'off';
            app.ReleaseTo_6 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_6.Enable = 'off';
            app.Tracking_6 = uieditfield(app.Panel, 'text');
            app.Tracking_6.Enable = 'off';
            app.DropDown_6 = uidropdown(app.Panel);
            app.DropDown_6.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_6.Enable = 'off';
            app.DropDown_6.Value = '';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Tooltip = {'MOS2E45230828'};
            app.Label_7.Text = '8';
            app.InactiveButton_7 = uibutton(app.Panel, 'state');
            app.InactiveButton_7.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_7.Tag = '_8';
            app.InactiveButton_7.Text = 'Inactive';
            app.DatePicker_7 = uidatepicker(app.Panel);
            app.DatePicker_7.Enable = 'off';
            app.ReleaseTo_7 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_7.Enable = 'off';
            app.Tracking_7 = uieditfield(app.Panel, 'text');
            app.Tracking_7.Enable = 'off';
            app.DropDown_7 = uidropdown(app.Panel);
            app.DropDown_7.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_7.Enable = 'off';
            app.DropDown_7.Value = '';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Tooltip = {'MOS2E45230805'};
            app.Label_8.Text = '9';
            app.InactiveButton_8 = uibutton(app.Panel, 'state');
            app.InactiveButton_8.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_8.Tag = '_9';
            app.InactiveButton_8.Text = 'Inactive';
            app.DatePicker_8 = uidatepicker(app.Panel);
            app.DatePicker_8.Enable = 'off';
            app.ReleaseTo_8 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_8.Enable = 'off';
            app.Tracking_8 = uieditfield(app.Panel, 'text');
            app.Tracking_8.Enable = 'off';
            app.DropDown_8 = uidropdown(app.Panel);
            app.DropDown_8.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_8.Enable = 'off';
            app.DropDown_8.Value = '';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Tooltip = {'MOS2E45230914'};
            app.Label_9.Text = '10';
            app.InactiveButton_9 = uibutton(app.Panel, 'state');
            app.InactiveButton_9.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_9.Tag = '_10';
            app.InactiveButton_9.Text = 'Inactive';
            app.DatePicker_9 = uidatepicker(app.Panel);
            app.DatePicker_9.Enable = 'off';
            app.ReleaseTo_9 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_9.Enable = 'off';
            app.Tracking_9 = uieditfield(app.Panel, 'text');
            app.Tracking_9.Enable = 'off';
            app.DropDown_9 = uidropdown(app.Panel);
            app.DropDown_9.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_9.Enable = 'off';
            app.DropDown_9.Value = '';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Tooltip = {'MOS2E34230093'};
            app.Label_10.Text = '6';
            app.InactiveButton_10 = uibutton(app.Panel, 'state');
            app.InactiveButton_10.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_10.Tag = '_6';
            app.InactiveButton_10.Text = 'Inactive';
            app.DatePicker_10 = uidatepicker(app.Panel);
            app.DatePicker_10.Enable = 'off';
            app.ReleaseTo_10 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_10.Enable = 'off';
            app.Tracking_10 = uieditfield(app.Panel, 'text');
            app.Tracking_10.Enable = 'off';
            app.DropDown_10 = uidropdown(app.Panel);
            app.DropDown_10.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_10.Enable = 'off';
            app.DropDown_10.Value = '';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Tooltip = {'MOS2E45230848'};
            app.Label_11.Text = '11';
            app.InactiveButton_11 = uibutton(app.Panel, 'state');
            app.InactiveButton_11.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_11.Tag = '_11';
            app.InactiveButton_11.Text = 'Inactive';
            app.DatePicker_11 = uidatepicker(app.Panel);
            app.DatePicker_11.Enable = 'off';
            app.ReleaseTo_11 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_11.Enable = 'off';
            app.Tracking_11 = uieditfield(app.Panel, 'text');
            app.Tracking_11.Enable = 'off';
            app.DropDown_11 = uidropdown(app.Panel);
            app.DropDown_11.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_11.Enable = 'off';
            app.DropDown_11.Value = '';
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Tooltip = {'MOS2E45230896'};
            app.Label_12.Text = '12';
            app.InactiveButton_12 = uibutton(app.Panel, 'state');
            app.InactiveButton_12.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_12.Tag = '_12';
            app.InactiveButton_12.Text = 'Inactive';
            app.DatePicker_12 = uidatepicker(app.Panel);
            app.DatePicker_12.Enable = 'off';
            app.ReleaseTo_12 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_12.Enable = 'off';
            app.Tracking_12 = uieditfield(app.Panel, 'text');
            app.Tracking_12.Enable = 'off';
            app.DropDown_12 = uidropdown(app.Panel);
            app.DropDown_12.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_12.Enable = 'off';
            app.DropDown_12.Value = '';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Tooltip = {'MOS2E45230887 '};
            app.Label_13.Text = '13';
            app.InactiveButton_13 = uibutton(app.Panel, 'state');
            app.InactiveButton_13.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_13.Tag = '_13';
            app.InactiveButton_13.Text = 'Inactive';
            app.DatePicker_13 = uidatepicker(app.Panel);
            app.DatePicker_13.Enable = 'off';
            app.ReleaseTo_13 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_13.Enable = 'off';
            app.Tracking_13 = uieditfield(app.Panel, 'text');
            app.Tracking_13.Enable = 'off';
            app.DropDown_13 = uidropdown(app.Panel);
            app.DropDown_13.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_13.Enable = 'off';
            app.DropDown_13.Value = '';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Tooltip = {'MOS2E45230853'};
            app.Label_14.Text = '14';
            app.InactiveButton_14 = uibutton(app.Panel, 'state');
            app.InactiveButton_14.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_14.Tag = '_14';
            app.InactiveButton_14.Text = 'Inactive';
            app.DatePicker_14 = uidatepicker(app.Panel);
            app.DatePicker_14.Enable = 'off';
            app.ReleaseTo_14 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_14.Enable = 'off';
            app.Tracking_14 = uieditfield(app.Panel, 'text');
            app.Tracking_14.Enable = 'off';
            app.DropDown_14 = uidropdown(app.Panel);
            app.DropDown_14.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_14.Enable = 'off';
            app.DropDown_14.Value = '';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Tooltip = {'MOS2E45230865'};
            app.Label_15.Text = '15';
            app.InactiveButton_15 = uibutton(app.Panel, 'state');
            app.InactiveButton_15.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_15.Tag = '_15';
            app.InactiveButton_15.Text = 'Inactive';
            app.DatePicker_15 = uidatepicker(app.Panel);
            app.DatePicker_15.Enable = 'off';
            app.ReleaseTo_15 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_15.Enable = 'off';
            app.Tracking_15 = uieditfield(app.Panel, 'text');
            app.Tracking_15.Enable = 'off';
            app.DropDown_15 = uidropdown(app.Panel);
            app.DropDown_15.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_15.Enable = 'off';
            app.DropDown_15.Value = '';
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Tooltip = {'MOS2E49240280'};
            app.Label_16.Text = '16';
            app.InactiveButton_16 = uibutton(app.Panel, 'state');
            app.InactiveButton_16.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_16.Tag = '_16';
            app.InactiveButton_16.Text = 'Inactive';
            app.DatePicker_16 = uidatepicker(app.Panel);
            app.DatePicker_16.Enable = 'off';
            app.ReleaseTo_16 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_16.Enable = 'off';
            app.Tracking_16 = uieditfield(app.Panel, 'text');
            app.Tracking_16.Enable = 'off';
            app.DropDown_16 = uidropdown(app.Panel);
            app.DropDown_16.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_16.Enable = 'off';
            app.DropDown_16.Value = '';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Tooltip = {'MOS2E41240888'};
            app.Label_17.Text = '17';
            app.InactiveButton_17 = uibutton(app.Panel, 'state');
            app.InactiveButton_17.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_17.Tag = '_17';
            app.InactiveButton_17.Text = 'Inactive';
            app.DatePicker_17 = uidatepicker(app.Panel);
            app.DatePicker_17.Enable = 'off';
            app.ReleaseTo_17 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_17.Enable = 'off';
            app.Tracking_17 = uieditfield(app.Panel, 'text');
            app.Tracking_17.Enable = 'off';
            app.DropDown_17 = uidropdown(app.Panel);
            app.DropDown_17.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_17.Enable = 'off';
            app.DropDown_17.Value = '';
            app.Label_18 = uilabel(app.Panel);
            app.Label_18.Tooltip = {'MOS2E42240099 '};
            app.Label_18.Text = '18';
            app.InactiveButton_18 = uibutton(app.Panel, 'state');
            app.InactiveButton_18.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_18.Tag = '_18';
            app.InactiveButton_18.Text = 'Inactive';
            app.DatePicker_18 = uidatepicker(app.Panel);
            app.DatePicker_18.Enable = 'off';
            app.ReleaseTo_18 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_18.Enable = 'off';
            app.Tracking_18 = uieditfield(app.Panel, 'text');
            app.Tracking_18.Enable = 'off';
            app.DropDown_18 = uidropdown(app.Panel);
            app.DropDown_18.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_18.Enable = 'off';
            app.DropDown_18.Value = '';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Tooltip = {'MOS2E41240857'};
            app.Label_19.Text = '19';
            app.InactiveButton_19 = uibutton(app.Panel, 'state');
            app.InactiveButton_19.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_19.Tag = '_19';
            app.InactiveButton_19.Text = 'Inactive';
            app.DatePicker_19 = uidatepicker(app.Panel);
            app.DatePicker_19.Enable = 'off';
            app.ReleaseTo_19 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_19.Enable = 'off';
            app.Tracking_19 = uieditfield(app.Panel, 'text');
            app.Tracking_19.Enable = 'off';
            app.DropDown_19 = uidropdown(app.Panel);
            app.DropDown_19.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_19.Enable = 'off';
            app.DropDown_19.Value = '';
            app.Label_20 = uilabel(app.Panel);
            app.Label_20.Tooltip = {'MOS2E45230865'};
            app.Label_20.Text = '20';
            app.InactiveButton_20 = uibutton(app.Panel, 'state');
            app.InactiveButton_20.ValueChangedFcn = createCallbackFcn(app, @statusButtonValueChanged, true);
            app.InactiveButton_20.Tag = '_20';
            app.InactiveButton_20.Text = 'Inactive';
            app.DatePicker_20 = uidatepicker(app.Panel);
            app.DatePicker_20.Enable = 'off';
            app.ReleaseTo_20 = uieditfield(app.Panel, 'text');
            app.ReleaseTo_20.Enable = 'off';
            app.Tracking_20 = uieditfield(app.Panel, 'text');
            app.Tracking_20.Enable = 'off';
            app.DropDown_20 = uidropdown(app.Panel);
            app.DropDown_20.Items = {'', '3-Month', '6-Month', '9-Month', '12-Month'};
            app.DropDown_20.Enable = 'off';
            app.DropDown_20.Value = '';
            app.History = uitab(app.TabGroup);
            app.mo3 = uibutton(app.History, 'push');
            app.mo3.Enable = 'off';
            app.mo3.Text = '3-Month';
            app.mo6 = uibutton(app.History, 'push');
            app.mo6.Enable = 'off';
            app.mo6.Text = '6-Month';
            app.mo9 = uibutton(app.History, 'push');
            app.mo9.Enable = 'off';
            app.mo9.Text = '9-Month';
            app.mo12 = uibutton(app.History, 'push');
            app.mo12.Enable = 'off';
            app.mo12.Text = '12-Month';
            app.UITable = uitable(app.History);
            app.UITable.ColumnName = {'SubID'; 'ID'; 'Start'; 'Return'; 'ID'; 'Start'; 'Return'; 'ID'; 'Start'; 'Return'; 'ID'; 'Start'; 'Return'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true true true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Icon = 'information-button.png';
            app.InfoButton.Tooltip = {''};
            app.InfoButton.Text = '';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HistoryButton = uibutton(app.UIFigure, 'push');
            app.HistoryButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryButtonPushed, true);
            app.HistoryButton.Text = 'History';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceTracking_prod

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end